classdef Gears < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PowerDropDownLabel              matlab.ui.control.Label
        PowerDropDown                   matlab.ui.control.DropDown
        PinionspeedDropDownLabel        matlab.ui.control.Label
        PinionspeedDropDown             matlab.ui.control.DropDown
        GearspeedEditFieldLabel         matlab.ui.control.Label
        GearspeedEditField              matlab.ui.control.NumericEditField
        NewVelocityRatioEditFieldLabel  matlab.ui.control.Label
        NewVelocityRatioEditField       matlab.ui.control.NumericEditField
        NewGearSpeedEditFieldLabel      matlab.ui.control.Label
        NewGearSpeedEditField           matlab.ui.control.NumericEditField
        GearteethEditFieldLabel         matlab.ui.control.Label
        GearteethEditField              matlab.ui.control.NumericEditField
        PinionteethEditFieldLabel       matlab.ui.control.Label
        PinionteethEditField            matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        InputsLabel                     matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        PinionDiameterEditFieldLabel    matlab.ui.control.Label
        PinionDiameterEditField         matlab.ui.control.EditField
        GearDiameterEditFieldLabel      matlab.ui.control.Label
        GearDiameterEditField           matlab.ui.control.EditField
        PinionSpeedEditFieldLabel       matlab.ui.control.Label
        PinionSpeedEditField            matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            P = str2double(app.PowerDropDown.Value);
            pinion_speed = int64(str2double(app.PinionspeedDropDown.Value));
            gear_speed = int64(app.GearspeedEditField.Value);
           
            
            Pinon20_Interferace = [13 14 15 16 17 18];
            Gear20_Interferace = [16 26 45 101 1309 Inf];
            
            Power = [0.05 0.1 0.25 0.33 0.5 0.75 1 1.5 2 3 5 7.5 10 15 20 25 30 40 50];
            rpm50 = [20 16 12 10 10 8 6 6 6 5 4 4 3 2 2];
            rpm100 = [20 20 16 12 12 10 10 8 6 6 5 5 4 4 3 3 2 2];
            rpm300 = [24 20 20 16 16 12 12 12 10 8 6 6 6 5 4 4 4 3 3];
            rpm600 = [32 24 20 20 20 16 16 12 12 10 8 8 6 6 5 5 4 4 4];
            rpm900 = [32 24 24 20 20 16 16 16 12 12 10 8 6 6 6 5 5 4 4];
            rpm1200 = [32 24 24 24 20 20 16 16 12 12 10 8 8 6 6 5 5];
            rpm1800 = [32 32 24 24 20 20 20 16 16 12 12 10 8 6 6 6 5];
            rpm2400 = [32 32 24 24 24 20 20 16 16 12 12 10 8 6 6 5];
            rpm3600 = [32 32 24 24 24 20 20 20 16 16 12 10 10 8];
            
            Pd32 = [12 14 16 18 20 24 28 32 36 40 48 56 64 72 80 96 112 128];
            Pd24 = [12 15 18 21 24 27 30 36 42 48 54 60 72 84 96 120 144];
            Pd20 = [12 14 15 16 18 20 24 25 30 35 40 45 50 60 70 80 84 90 100 120 140 160 180 200];
            Pd16 = [12 14 15 16 18 20 24 28 30 32 36 40 48 56 60 64 72 80 96 128 144 160 192];
            Pd12 = [12 13 14 115 16 18 20 21 24 28 30 36 40 48 56 60 64 72 80 96 128 144 160 192];
            Pd10 = [12 14 15 16 18 20 24 25 28 30 35 40 45 48 50 55 60 70 80 90 100 120 140 160 200];
            Pd8 = [12 14 15 16 18 20 22 24 28 32 36 40 44 48 56 60 64 72 80 88 96 112 120 128];
            Pd6 = [12 14 15 16 18 21 24 27 30 33 36 42 48 54 60 66 72 84 96 108 120];
            Pd5 = [12 14 15 16 18 20 24 25 28 30 35 40 45 50 60 70 80 100 110 120 140 160 180];
            
            
            Driven = 0;
            
            if pinion_speed == 50
                Diametral_Pitch = rpm50(find(Power == P));
            elseif pinion_speed == 100
                Diametral_Pitch = rpm100(find(Power == P));
            elseif pinion_speed == 300
                Diametral_Pitch = rpm300(find(Power == P));
            elseif pinion_speed == 600
                Diametral_Pitch = rpm600(find(Power == P));
            elseif pinion_speed == 900
                Diametral_Pitch = rpm900(find(Power == P));
            elseif pinion_speed == 1200
                Diametral_Pitch = rpm1200(find(Power == P));
            elseif pinion_speed == 1800
                Diametral_Pitch = rpm1800(find(Power == P));
            elseif pinion_speed == 2400
                Diametral_Pitch = rpm2400(find(Power == P));
            elseif pinion_speed == 3600
                Diametral_Pitch = rpm3600(find(Power == P));
            end
            
            if Diametral_Pitch == 32
                Pd = Pd32;
            elseif Diametral_Pitch == 24
                Pd = Pd24;
            elseif Diametral_Pitch == 20
                Pd = Pd20;
            elseif Diametral_Pitch == 16
                Pd = Pd16;
            elseif Diametral_Pitch == 12
                Pd = Pd12;
            elseif Diametral_Pitch == 10
                Pd = Pd10;
            elseif Diametral_Pitch == 8
                Pd = Pd8;
            elseif Diametral_Pitch == 6
                Pd = Pd6;
            else
                Pd = Pd5;
                
            end
            
            VR = pinion_speed/gear_speed;
            count1 = 1;
            count2 = 1;
            Probabilities = [];
            for i= 1:1:length(Pinon20_Interferace)
                N_driver = Pinon20_Interferace(i);
                N_driven = N_driver*VR;
                
                if(N_driven <= Gear20_Interferace(i))
                    NDriver(count1) = N_driver;
                    Nteeth(count1) = N_driven;
                    count1 = count1 + 1;
                    if(ismember(N_driven,double(Pd)))
                        Driver = N_driver;
                        Probabilities(count2) = N_driven;
                        count2 = count2 + 1;
                    end
                end
            end
            
            
            if length(Probabilities) == 0
                Nteeth = int64(Nteeth);
                for i = 1:1:length(Nteeth)
                    if ismember(Nteeth(i),Pd)
                        Driven = Nteeth(i);
                        Driver = NDriver(i);
                    end
                end
            else
                Driven = Probabilities(1);
            end
            
            while(Driven == 0)
                Nteeth = Nteeth - 1;
                for i = 1:1:length(Nteeth)
                    if ismember(Nteeth(i),Pd)
                        Driven = Nteeth(i);
                        Driver = NDriver(i);
                    end
                end
            end
            
            app.PinionteethEditField.Value = Driver;
            app.GearteethEditField.Value = double(Driven);
            new_VR = double(Driven)/double(Driver);
            app.NewVelocityRatioEditField.Value = new_VR;
            app.NewGearSpeedEditField.Value = double(pinion_speed)/new_VR;
            app.PinionSpeedEditField.Value = num2str(pinion_speed);
            app.PinionDiameterEditField.Value = num2str(Driver/Diametral_Pitch);
            app.GearDiameterEditField.Value = num2str(Driven/Diametral_Pitch);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerDropDownLabel = uilabel(app.UIFigure);
            app.PowerDropDownLabel.Text = 'Power';
            app.PowerDropDown = uidropdown(app.UIFigure);
            app.PowerDropDown.Items = {'0.05', '0.1', '0.25', '0.33', '0.5', '0.75', '1', '1.5', '2', '3', '5', '7.5', '10', '15', '20', '25', '30', '40', '50'};
            app.PowerDropDown.Value = '10';
            app.PinionspeedDropDownLabel = uilabel(app.UIFigure);
            app.PinionspeedDropDownLabel.Text = 'Pinion speed';
            app.PinionspeedDropDown = uidropdown(app.UIFigure);
            app.PinionspeedDropDown.Items = {'50', '100', '300', '600', '900', '1200', '1800', '2400', '3600'};
            app.PinionspeedDropDown.Value = '50';
            app.GearspeedEditFieldLabel = uilabel(app.UIFigure);
            app.GearspeedEditFieldLabel.Text = 'Gear  speed';
            app.GearspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewVelocityRatioEditFieldLabel = uilabel(app.UIFigure);
            app.NewVelocityRatioEditFieldLabel.Text = 'New Velocity Ratio';
            app.NewVelocityRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewGearSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.NewGearSpeedEditFieldLabel.Text = 'New Gear Speed';
            app.NewGearSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewGearSpeedEditField.Editable = 'off';
            app.GearteethEditFieldLabel = uilabel(app.UIFigure);
            app.GearteethEditFieldLabel.Text = 'Gear teeth';
            app.GearteethEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinionteethEditFieldLabel = uilabel(app.UIFigure);
            app.PinionteethEditFieldLabel.Text = 'Pinion teeth';
            app.PinionteethEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.PinionDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.PinionDiameterEditFieldLabel.Text = 'Pinion Diameter';
            app.PinionDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.GearDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.GearDiameterEditFieldLabel.Text = 'Gear Diameter';
            app.GearDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.PinionSpeedEditFieldLabel = uilabel(app.UIFigure);
            app.PinionSpeedEditFieldLabel.Text = 'Pinion Speed';
            app.PinionSpeedEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gears

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end